classdef read_setting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        XLSXLabel             matlab.ui.control.Label
        editor_xlsx_filepath  matlab.ui.control.EditField
        button_xlsx_filepath  matlab.ui.control.Button
        SLDDLabel             matlab.ui.control.Label
        editor_sldd_filepath  matlab.ui.control.EditField
        button_sldd_filepath  matlab.ui.control.Button
        check_import_param    matlab.ui.control.CheckBox
        check_import_struct   matlab.ui.control.CheckBox
        check_import_enum     matlab.ui.control.CheckBox
        check_import_signal   matlab.ui.control.CheckBox
        check_import_config   matlab.ui.control.CheckBox
        button_apply          matlab.ui.control.Button
        button_okay           matlab.ui.control.Button
        button_cancel         matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            sys = bdroot;
            if ~isempty(sys)
                xlsx = which([sys, '.xlsx']);
                if ~isempty(xlsx)
                    app.editor_xlsx_filepath.Value = xlsx;
                end
                sldd = which([sys, '.sldd']);
                if ~isempty(sldd)
                    app.editor_sldd_filepath.Value  = sldd;
                end
            end
        end

        % Button pushed function: button_xlsx_filepath
        function button_xlsx_filepathButtonPushed(app, event)
            [filename, pathname] = uigetfile(...
                {'*.xls;*.xlsx','Excel文件 (*.xls,*.xlsx)'
                '*.*',          '所有文件 (*.*)'},...
                'Excel 配置文件');
            if ~isequal(filename, 0)
                app.editor_xlsx_filepath.Value = fullfile(pathname, filename);
            end
        end

        % Button pushed function: button_sldd_filepath
        function button_sldd_filepathButtonPushed(app, event)
            [filename, pathname] = uigetfile(...
                {'*.sldd','SLDD文件 (*.sldd)'
                '*.*',          '所有文件 (*.*)'},...
                '数据字典文件');
            if ~isequal(filename, 0)
                app.editor_xlsx_filepath.Value = fullfile(pathname, filename);
            end
        end

        % Button pushed function: button_apply
        function button_applyButtonPushed(app, event)
            sldd = app.editor_sldd_filepath;
            xlsx = app.editor_xlsx_filepath;
            if isempty(xlsx)
                return;
            elseif isempty(sldd)
                return;
            end
            if app.check_import_param.Value
                nextpilot.simulink.read_excel_param(xlsx, sldd);
            end
            if app.check_import_struct.Value
                nextpilot.simulink.read_excel_struct(xlsx, sldd);
            end
            if app.check_import_enum.Value
                nextpilot.simulink.read_excel_enum(xlsx, sldd);
            end
        end

        % Button pushed function: button_okay
        function button_okayButtonPushed(app, event)
            button_applyButtonPushed(app, event);
            close(gcf);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.XLSXLabel = uilabel(app.UIFigure);
            app.XLSXLabel.Text = 'XLSX 路径：';
            app.editor_xlsx_filepath = uieditfield(app.UIFigure, 'text');
            app.button_xlsx_filepath = uibutton(app.UIFigure, 'push');
            app.button_xlsx_filepath.ButtonPushedFcn = createCallbackFcn(app, @button_xlsx_filepathButtonPushed, true);
            app.button_xlsx_filepath.Text = '浏览...';
            app.SLDDLabel = uilabel(app.UIFigure);
            app.SLDDLabel.Text = 'SLDD 路径：';
            app.editor_sldd_filepath = uieditfield(app.UIFigure, 'text');
            app.button_sldd_filepath = uibutton(app.UIFigure, 'push');
            app.button_sldd_filepath.ButtonPushedFcn = createCallbackFcn(app, @button_sldd_filepathButtonPushed, true);
            app.button_sldd_filepath.Text = '浏览...';
            app.check_import_param = uicheckbox(app.UIFigure);
            app.check_import_param.Text = 'Parameter';
            app.check_import_struct = uicheckbox(app.UIFigure);
            app.check_import_struct.Text = 'Bus Object';
            app.check_import_enum = uicheckbox(app.UIFigure);
            app.check_import_enum.Text = 'Enumeration';
            app.check_import_signal = uicheckbox(app.UIFigure);
            app.check_import_signal.Text = 'Signal';
            app.check_import_config = uicheckbox(app.UIFigure);
            app.check_import_config.Text = 'Configuration';
            app.button_apply = uibutton(app.UIFigure, 'push');
            app.button_apply.ButtonPushedFcn = createCallbackFcn(app, @button_applyButtonPushed, true);
            app.button_apply.Text = '应用';
            app.button_okay = uibutton(app.UIFigure, 'push');
            app.button_okay.ButtonPushedFcn = createCallbackFcn(app, @button_okayButtonPushed, true);
            app.button_okay.Text = '确定';
            app.button_cancel = uibutton(app.UIFigure, 'push');
            app.button_cancel.Text = '取消';
        end
    end

    methods (Access = public)

        % Construct app
        function app = read_setting

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end